classdef BRDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConfirmLabel                  matlab.ui.control.Label
        CountLabel                    matlab.ui.control.Label
        getRatingButton               matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        ConnectBluetoothButton        matlab.ui.control.Button
        pulseWidthEditField           matlab.ui.control.NumericEditField
        pulseWidthEditFieldLabel      matlab.ui.control.Label
        hzEditField                   matlab.ui.control.NumericEditField
        hzEditFieldLabel              matlab.ui.control.Label
        setDataMatrixButton           matlab.ui.control.Button
        SubmitButton                  matlab.ui.control.Button
        SendMessageEditField          matlab.ui.control.EditField
        SendMessageEditFieldLabel     matlab.ui.control.Label
        nameDataMatrixEditField       matlab.ui.control.EditField
        nameDataMatrixEditFieldLabel  matlab.ui.control.Label
        loadwavfileButton             matlab.ui.control.Button
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Arduino % Description
        fileName % Description
        message % Description
        sineWave % Description
        count % Description
        hz % Description
        freq % Description
        pulseWidth % Description
        rating % Description
        confirm % Description
        data 
        cData
        countChar
        confirmChar
    end
        
    
    methods (Access = private)
        function displayCharData(app)
            %[data] = read(app);
            %disp(data)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: nameDataMatrixEditField
        function nameDataMatrixEditFieldValueChanged(app, event)
            app.fileName = app.nameDataMatrixEditField.Value;
            % app.fileName = app.fileName + ".mat"
            % save(app.fileName)

        end

        % Button pushed function: loadwavfileButton
        function loadwavfileButtonPushed(app, event)
%           wave = (app.amplitude1)*sin((app.frequency1)*t +app.phaseShift1) + app.amplitude2*cos((app.frequency2)*t+app.phaseShift2)

            % is a piecewise function even necessary ????
% what about just a constant hz val rather than constant burst 
% in that case:
            y = app.hz
            TotalTime = app.pulseWidth
%           y = piecewise(0 < x < app.pulseWidth ,0,app.length > x < 2*app.pulseWidth,app.hz, 2*app.pulseWidth < x < 3*app.pulseWidth,0, 3*app.pulseWidth > x < 4*app.pulseWidth,app.hz)
%           TotalTime = 4*app.pulseWidth./4
            write(device,y)
            pause(TotalTime)

%           soundsc(y, app.freq)

%             bt = bluetooth("XXX",1);
%             numVals = bt.NumBytesAvailable
%             val = read(bt,numVals);
%             app.rating = strsplit(val , "");
%             app.rating = app.rating.';
%             app.rating = str2double(app.rating)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % if (app.hz&& app.pulseWidth)
            %     a = arduino("F3996AE0-F589-1ADA-97C5-8AAAAC4CB137")
            %     writeDigitalPin(a,'TX0',1)
            % end
        end

        % Value changed function: SendMessageEditField
        function SendMessageEditFieldValueChanged(app, event)
            app.message = app.SendMessageEditField.Value;
            synthesizer = speechClient("IBM");
            [speech, fs] = text2speech(synthesizer, app.message);
            soundsc(speech, fs);            
        end

        % Button pushed function: setDataMatrixButton
        function setDataMatrixButtonPushed(app, event)
            dataSet = [app.hz, app.pulseWidth, app.rating]
            % a = cat(1, a, dataSet)
            assignin('base', app.fileName, dataSet);
            %save(app.fileName,'-append','a')
        end

        % Value changed function: hzEditField
        function hzEditFieldValueChanged(app, event)
            app.hz = app.hzEditField.Value;
        end

        % Value changed function: pulseWidthEditField
        function pulseWidthEditFieldValueChanged(app, event)
            app.pulseWidth = app.pulseWidthEditField.Value;
        end

        % Button pushed function: getRatingButton
        function getRatingButtonPushed(app, event)
            app.Lamp.Color = 'green'
            app.countChar = characteristic(app.Arduino,"EE4130AB-85EF-4603-ACB4-A2E694CEEFCD","D06BFECD-8A97-44A1-B761-F3C5228C7BBA")
            app.confirmChar = characteristic(app.Arduino,"EE4130AB-85EF-4603-ACB4-A2E694CEEFCD","D06BFECD-8A97-44A1-B761-F3C5228C7AAB")
            %app.countChar.DataAvailableFcn = @displayCharData
            %app.confirmChar.DataAvailableFcn = @displayCharData
            %subscribe(app.countChar,'notification')
            %subscribe(app.confirmChar,'notification')
           % if loop so that cData confirms selection
            while(read(app.confirmChar) == 0)
                app.cData = read(app.confirmChar);
                %disp(app.data)
                %disp(app.cData)
            end  
            app.rating = read(app.countChar)
            write(app.confirmChar, 0)
            write(app.countChar, 0)
            app.Lamp.Color = 'cyan'
        end

        % Button pushed function: ConnectBluetoothButton
        function ConnectBluetoothButtonPushed(app, event)
            lamp = app.Lamp
            countField = app.CountLabel
            confirmField = app.ConfirmLabel
            if (~(isempty(blelist("Name", "ButtonMonitor"))))
                app.Arduino = ble("ButtonMonitor")
                lamp.Color = 'green'
                
 
                %countField.Text = countField.Text + read(countChar)
                %confirmField.Text = confirmField.Text + read(confirmChar)
            else
                countField.Text = "Count: N/A"
                confirmField.Text = "Confirm: N/A"
                lamp.Color = 'red'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadwavfileButton = uibutton(app.UIFigure, 'push');
            app.loadwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadwavfileButtonPushed, true);
            app.loadwavfileButton.Text = 'load .wav file ';
            app.nameDataMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.nameDataMatrixEditFieldLabel.Text = 'nameDataMatrix';
            app.nameDataMatrixEditField = uieditfield(app.UIFigure, 'text');
            app.nameDataMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @nameDataMatrixEditFieldValueChanged, true);
            app.SendMessageEditFieldLabel = uilabel(app.UIFigure);
            app.SendMessageEditFieldLabel.Text = 'Send Message';
            app.SendMessageEditField = uieditfield(app.UIFigure, 'text');
            app.SendMessageEditField.ValueChangedFcn = createCallbackFcn(app, @SendMessageEditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.setDataMatrixButton = uibutton(app.UIFigure, 'push');
            app.setDataMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @setDataMatrixButtonPushed, true);
            app.setDataMatrixButton.Text = 'setDataMatrix';
            app.hzEditFieldLabel = uilabel(app.UIFigure);
            app.hzEditFieldLabel.Text = 'hz';
            app.hzEditField = uieditfield(app.UIFigure, 'numeric');
            app.hzEditField.ValueChangedFcn = createCallbackFcn(app, @hzEditFieldValueChanged, true);
            app.pulseWidthEditFieldLabel = uilabel(app.UIFigure);
            app.pulseWidthEditFieldLabel.Text = 'pulseWidth';
            app.pulseWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.pulseWidthEditField.ValueChangedFcn = createCallbackFcn(app, @pulseWidthEditFieldValueChanged, true);
            app.ConnectBluetoothButton = uibutton(app.UIFigure, 'push');
            app.ConnectBluetoothButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBluetoothButtonPushed, true);
            app.ConnectBluetoothButton.Text = 'Connect Bluetooth';
            app.Lamp = uilamp(app.UIFigure);
            app.getRatingButton = uibutton(app.UIFigure, 'push');
            app.getRatingButton.ButtonPushedFcn = createCallbackFcn(app, @getRatingButtonPushed, true);
            app.getRatingButton.Text = 'getRating';
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = 'Count';
            app.ConfirmLabel = uilabel(app.UIFigure);
            app.ConfirmLabel.Text = 'Confirm';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Lamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRDS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end